classdef FGO_KF_Simulator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FactorGraphOptimizationSimulatorUIFigure  matlab.ui.Figure
        Panel                          matlab.ui.container.Panel
        TabGroup                       matlab.ui.container.TabGroup
        ComparisonbetweenKFVandFGOTab  matlab.ui.container.Tab
        Panel_5                        matlab.ui.container.Panel
        KernelDropDown                 matlab.ui.control.DropDown
        KernelDropDownLabel            matlab.ui.control.Label
        ThresIterEditField             matlab.ui.control.EditField
        ThresIterLabel                 matlab.ui.control.Label
        MaxIterEditField               matlab.ui.control.EditField
        MaxIterEditFieldLabel          matlab.ui.control.Label
        WinSizeEditField               matlab.ui.control.EditField
        WinSizeEditFieldLabel          matlab.ui.control.Label
        RobustDelEditField             matlab.ui.control.EditField
        RobustDelLabel                 matlab.ui.control.Label
        PropaNoiseEditField            matlab.ui.control.EditField
        PropaNoiseEditFieldLabel       matlab.ui.control.Label
        InitCovEditField_2             matlab.ui.control.EditField
        InitCovEditField_2Label        matlab.ui.control.Label
        KFtypeDropDown                 matlab.ui.control.DropDown
        KFtypeDropDownLabel            matlab.ui.control.Label
        KFVariantsLabel                matlab.ui.control.Label
        SWFGOSimulationTab             matlab.ui.container.Tab
        Panel_6                        matlab.ui.container.Panel
        AutoDiffDropDown               matlab.ui.control.DropDown
        AutoDiffDropDownLabel          matlab.ui.control.Label
        ThresIterEditField_2           matlab.ui.control.EditField
        ThresIterEditField_2Label      matlab.ui.control.Label
        MaxIterEditField_2             matlab.ui.control.EditField
        MaxIterEditField_2Label        matlab.ui.control.Label
        WinSizeEditField_2             matlab.ui.control.EditField
        WinSizeEditField_2Label        matlab.ui.control.Label
        RobustDelEditField_2           matlab.ui.control.EditField
        RobustDelEditField_2Label      matlab.ui.control.Label
        KernelDropDown_2               matlab.ui.control.DropDown
        KernelDropDown_2Label          matlab.ui.control.Label
        PropaNoiseEditField_2          matlab.ui.control.EditField
        PropaNoiseEditField_2Label     matlab.ui.control.Label
        InitCovEditField_3             matlab.ui.control.EditField
        InitCovEditField_3Label        matlab.ui.control.Label
        SWFGOLabel                     matlab.ui.control.Label
        DashboardPanel                 matlab.ui.container.Panel
        time_text                      matlab.ui.control.TextArea
        perf_text                      matlab.ui.control.TextArea
        algo_text                      matlab.ui.control.TextArea
        Button_6                       matlab.ui.control.Button
        Button_5                       matlab.ui.control.Button
        Button_4                       matlab.ui.control.Button
        Button_2                       matlab.ui.control.Button
        Button                         matlab.ui.control.Button
        ErrorSeriesAxes                matlab.ui.control.UIAxes
        TimeAxes                       matlab.ui.control.UIAxes
        CDFAxes                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end


    properties (Access = private)
        data = struct('current_mode', 'beginner', 'loaded_data', [], ...
            'kfv_results', [], 'fgo_results', [], 'cpu_usage', [], ...
            'selected_kf', 'KF', 'kfv_estimator', [])
        processing_time = struct('kfv_time', 0, 'fgo_time', 0)
        is_running = false
        swfgo_config = []
        swfgo_results = []
    end

    methods (Access = private)


        function [X_est, P_est, X_pred, P_pred] = kf(app, X_est_prev, P_est_prev, F, Q, B, U, Z, H, R)

            X_pred = F * X_est_prev + B * U;
            P_pred = F * P_est_prev * F' + Q;


            K = P_pred * H' / (H * P_pred * H' + R);
            X_est = X_pred + K * (Z - H * X_pred);
            P_est = (eye(size(K, 1)) - K * H) * P_pred;
        end

        function updateRightPanels(app)

            if ~isempty(app.CDFAxes)
                app.createCombinedCDFPlot(app.CDFAxes);
            end

            if ~isempty(app.TimeAxes)
                app.createProcessingTimePlot(app.TimeAxes);
            end

            if ~isempty(app.ErrorSeriesAxes)
                app.createErrorSeriesPlot(app.ErrorSeriesAxes);
            end
        end

        function createCombinedCDFPlot(app, ax)
            % 创建合并的CDF图
            cla(ax);
            hold(ax, 'on');

            % KFV CDF
            if ~isempty(app.data.kfv_results)
                errors = app.data.kfv_results.errors;
                [f, x] = ecdf(errors);
                plot(ax, x, f, 'b-', 'LineWidth', 2, 'DisplayName', sprintf('%s', app.data.selected_kf));
            end

            % FGO CDF
            if ~isempty(app.data.fgo_results)
                errors = app.data.fgo_results.errors;
                [f, x] = ecdf(errors);
                plot(ax, x, f, 'r--', 'LineWidth', 2, 'DisplayName', 'FGO');
            end

            xlabel(ax, 'Error (m)');
            ylabel(ax, 'Cumulative Probability');
            title(ax, 'Error CDF Comparison');
            legend(ax, 'show');
            grid(ax, 'on');
            hold(ax, 'off');
        end

        function createProcessingTimePlot(app, ax)

            cla(ax);


            has_kfv_time = isfield(app.processing_time, 'kfv_time') && app.processing_time.kfv_time > 0;
            has_fgo_time = isfield(app.processing_time, 'fgo_time') && app.processing_time.fgo_time > 0;

            if has_kfv_time || has_fgo_time

                algorithms = {};
                times = [];
                colors = [];

                if has_kfv_time
                    algorithms{end+1} = app.data.selected_kf;
                    times(end+1) = app.processing_time.kfv_time;
                    colors(end+1,:) = [0.2, 0.4, 0.8]; % blue
                end

                if has_fgo_time
                    algorithms{end+1} = 'FGO';
                    times(end+1) = app.processing_time.fgo_time;
                    colors(end+1,:) = [0.8, 0.2, 0.2]; % red
                end


                bar_handles = bar(ax, 1:length(times), times);


                if length(times) == 1
                    bar_handles.FaceColor = colors(1,:);
                else
                    bar_handles.FaceColor = 'flat';
                    for i = 1:length(times)
                        bar_handles.CData(i,:) = colors(i,:);
                    end
                end


                set(ax, 'XTick', 1:length(algorithms), 'XTickLabel', algorithms);
                ylabel(ax, 'Processing Time (ms)');
                title(ax, 'Processing Time Comparison');
                grid(ax, 'on');


                for i = 1:length(times)
                    text(ax, i, times(i) + max(times)*0.02, sprintf('%.3f ms', times(i)), ...
                        'HorizontalAlignment', 'center', 'FontSize', 8, 'FontWeight', 'bold');
                    % text(ax, i, times(i) + max(times)*0.02, sprintf('%s', times(i)), ...
                    %     'HorizontalAlignment', 'center', 'FontSize', 8, 'FontWeight', 'bold');
                end


                ylim(ax, [0, max(times)*1.15]);

            end
        end

        function createErrorSeriesPlot(app, ax)

            cla(ax);
            hold(ax, 'on');


            if ~isempty(app.data.kfv_results)
                errors = app.data.kfv_results.errors;
                plot(ax, 1:length(errors), errors, 'b.-', 'LineWidth', 1.5, ...
                    'DisplayName', sprintf('%s Error', app.data.selected_kf));
            end


            if ~isempty(app.data.fgo_results)
                errors = app.data.fgo_results.errors;
                plot(ax, 1:length(errors), errors, 'r.--', 'LineWidth', 1.5, ...
                    'DisplayName', 'FGO Error');
            end

            xlabel(ax, 'Time Step');
            ylabel(ax, 'Error (m)');
            title(ax, 'Error Time Series');
            legend(ax, 'show');
            grid(ax, 'on');
            hold(ax, 'off');
        end


        function updateDashboard(app, kfv_errors, fgo_errors)
            algo_text = app.algo_text;
            perf_text = app.perf_text;
            time_text = app.time_text;


            if ~isempty(algo_text)

                cfg = app.data.config;
                set(algo_text, 'Value', sprintf( ...
                    'Algorithm: %s + FGO\nMode: %s\nWinSize: %d, MaxIter: %d, Thres: %.1e, Kernel: %s(%.2f)', ...
                    'KFV', cfg.KFV.mode, cfg.KFV.window_size, cfg.KFV.max_iteration, ...
                    cfg.KFV.thres_iteration, cfg.KFV.robust_kernel, cfg.KFV.robust_delta));
            end


            if ~isempty(perf_text) && ~isempty(kfv_errors) && ~isempty(fgo_errors)
                kfv_rmse = sqrt(mean(kfv_errors.^2));
                fgo_rmse = sqrt(mean(fgo_errors.^2));
                set(perf_text, 'Value', sprintf([ ...
                    'RMSE KFV: %.3f m\nRMSE FGO: %.3f m\n' ...
                    'Illustrative single-run timing; not a benchmark.'], ...
                    kfv_rmse, fgo_rmse));
            end

            if ~isempty(time_text)
                set(time_text, 'Value', sprintf([ ...
                    'Mean KFV epoch: %.3f ms\nMean FGO epoch: %.3f ms\n' ...
                    'Measured once in this run.'], ...
                    app.processing_time.kfv_time, app.processing_time.fgo_time));
            end
        end

        function [est_positions, errors, processing_time] = runSelectedKFEstimator(app, data)
            tic;


            selected_kf = app.data.selected_kf;


            true_positions = data.true_positions;
            position_measurements = data.position_measurements;
            pdr_positions = data.pdr_positions;
            num_steps = data.num_steps;

            try
                switch selected_kf
                    % case 'KF'
                    %     estimator = KF();
                    case 'EKF'
                        estimator = EKF();
                        % case 'IEKF'
                        %     estimator = IEKF();
                    case 'REKF'
                        estimator = REKF();
                    case 'MIEKF'
                        estimator = MIEKF();
                    case 'RIEKF'
                        estimator = RMIEKF();
                    otherwise
                        estimator = KF();
                end


                result = estimator.run(data);
                est_positions = result.positions;

            catch ME
                warning('Failed to use %s estimator: %s. Using fallback KF.', selected_kf, ME.message);

                [est_positions, ~] = app.runFallbackKF(data);
            end


            errors = sqrt(sum((est_positions - true_positions').^2, 2));
            processing_time = toc;
        end

        function [est_positions, prd_positions] = runFallbackKF(app, data)

            true_positions = data.true_positions;
            position_measurements = data.position_measurements;
            pdr_positions = data.pdr_positions;
            num_steps = data.num_steps;

            pdr_noise_std = ones(2,1) * 0.5;
            position_noise_std = ones(2,1) * 0.5;

            X_est = position_measurements(:,1);
            P_est = diag(position_noise_std).^2;

            F = eye(2);
            Q = diag(pdr_noise_std).^2;
            H = eye(2);
            R = diag(position_noise_std).^2;
            B = eye(2);

            est_positions = X_est';
            prd_positions = X_est';

            for i = 1:num_steps
                U = pdr_positions(:,i);
                Z = position_measurements(:,i+1);

                [X_est, P_est, X_pred, ~] = app.kf(X_est, P_est, F, Q, B, U, Z, H, R);

                est_positions = [est_positions; X_est'];
                prd_positions = [prd_positions; X_pred'];
            end
        end

        function loadConfigFromScript(app)

            init_settings_kfv_fgo_comparison;
            app.data.config = config;


            if ~isfield(app.data.config, 'data') || ~isfield(app.data.config.data, 'path') || isempty(app.data.config.data.path)
                app.data.config.data.path = 'circle_cv_gmm_L1.mat';
            end
        end

        function root = projectRoot(~)
            root = fileparts(fileparts(mfilename('fullpath')));
        end

        function value = parsePositiveScalar(~, textValue, label)
            value = str2double(textValue);
            if ~isscalar(value) || ~isfinite(value) || value <= 0
                error('KFVFGO:InvalidParameter', ...
                    'Invalid %s Enter one finite value greater than zero.', label);
            end
        end

        function value = parsePositiveInteger(app, textValue, label)
            value = app.parsePositiveScalar(textValue, label);
            if fix(value) ~= value
                error('KFVFGO:InvalidParameter', ...
                    'Invalid %s Enter a positive integer.', label);
            end
        end

        function diagonal = parsePositiveDiagonal(~, textValue, label)
            normalized = regexprep(textValue, '[\[\],;]', ' ');
            diagonal = sscanf(normalized, '%f').';
            if numel(diagonal) ~= 4 || any(~isfinite(diagonal)) || any(diagonal <= 0)
                error('KFVFGO:InvalidParameter', ...
                    'Invalid %s Enter four finite positive values.', label);
            end
        end

        function releaseRunLock(app)
            app.is_running = false;
        end

        function fullPath = resolveDataPath(app, configuredPath)
            if isfile(configuredPath)
                fullPath = configuredPath;
                return;
            end

            fullPath = fullfile(app.projectRoot(), 'data', configuredPath);
            if ~isfile(fullPath)
                error('KFVFGO:DataFileNotFound', ...
                    'Configured dataset was not found: %s', configuredPath);
            end
        end

        function loadDataFromConfig(app)
            configuredPath = app.data.config.data.path;
            fullPath = app.resolveDataPath(configuredPath);
            app.data.loaded_data = validate_comparison_data(load(fullPath));
            app.algo_text.Value = sprintf( ...
                'Selected KF: %s\nStatus: Data Loaded\nData: %s', ...
                app.data.selected_kf, fullPath);
        end

        function populateUIFromConfig(app)
            cfg = app.data.config;


            app.KFtypeDropDown.Value = app.mapKfModeToUI(cfg.KFV.mode);   % 'EKF'/'IEKF'/'REKF'


            switch lower(cfg.KFV.robust_kernel)
                case 'huber',  app.KernelDropDown.Value = 'Huber';
                case 'cauchy', app.KernelDropDown.Value = 'Cauchy';
                case 'tukey', app.KernelDropDown.Value = 'Tukey';
                otherwise,     app.KernelDropDown.Value = 'None';
            end


            app.RobustDelEditField.Value = num2str(cfg.KFV.robust_delta);
            app.WinSizeEditField.Value   = num2str(cfg.KFV.window_size);
            app.MaxIterEditField.Value   = num2str(cfg.KFV.max_iteration);
            app.ThresIterEditField.Value = num2str(cfg.KFV.thres_iteration);


            app.InitCovEditField_2.Value = mat2str(diag(cfg.KFV.P0)');  % e.g. '[50, 50, 1, 1]'
            app.PropaNoiseEditField.Value = mat2str(diag(cfg.KFV.Q)');  % e.g. '[1e-4, 1e-4, 1e-4, 1e-4]'


            app.KFtypeDropDown.ValueChangedFcn = createCallbackFcn(app, @KFtypeDropDownValueChanged, true);
            app.KernelDropDown.ValueChangedFcn   = createCallbackFcn(app, @OnParamChanged, true);
            app.RobustDelEditField.ValueChangedFcn = createCallbackFcn(app, @OnParamChanged, true);
            app.WinSizeEditField.ValueChangedFcn   = createCallbackFcn(app, @OnParamChanged, true);
            app.MaxIterEditField.ValueChangedFcn   = createCallbackFcn(app, @OnParamChanged, true);
            app.ThresIterEditField.ValueChangedFcn = createCallbackFcn(app, @OnParamChanged, true);
            app.InitCovEditField_2.ValueChangedFcn = createCallbackFcn(app, @OnParamChanged, true);
            app.PropaNoiseEditField.ValueChangedFcn= createCallbackFcn(app, @OnParamChanged, true);
        end

        function loadSWFGOConfigFromScript(app)
            init_settings_swfgo;
            app.swfgo_config = config;
        end

        function populateSWUIFromConfig(app)
            cfg = app.swfgo_config.FGO;

            if logical(cfg.autoDiff)
                app.AutoDiffDropDown.Value = 'Yes';
            else
                app.AutoDiffDropDown.Value = 'No';
            end

            app.WinSizeEditField_2.Value   = num2str(cfg.window_size);
            app.MaxIterEditField_2.Value   = num2str(cfg.max_iteration);
            app.ThresIterEditField_2.Value = num2str(cfg.thres_iteration);
            app.RobustDelEditField_2.Value = num2str(cfg.robust_delta);
            app.InitCovEditField_3.Value   = mat2str(diag(cfg.P0)');
            app.PropaNoiseEditField_2.Value= mat2str(diag(cfg.Q)');
            switch lower(cfg.robust_kernel)
                case 'huber',  app.KernelDropDown_2.Value = 'Huber';
                case 'cauchy', app.KernelDropDown_2.Value = 'Cauchy';
                case 'tukey', app.KernelDropDown_2.Value = 'Tukey';
                otherwise,     app.KernelDropDown_2.Value = 'None';
            end


            app.WinSizeEditField_2.ValueChangedFcn   = @(src,evt)app.OnSWParamChanged();
            app.MaxIterEditField_2.ValueChangedFcn   = @(src,evt)app.OnSWParamChanged();
            app.ThresIterEditField_2.ValueChangedFcn = @(src,evt)app.OnSWParamChanged();
            app.RobustDelEditField_2.ValueChangedFcn = @(src,evt)app.OnSWParamChanged();
            app.InitCovEditField_3.ValueChangedFcn   = @(src,evt)app.OnSWParamChanged();
            app.PropaNoiseEditField_2.ValueChangedFcn= @(src,evt)app.OnSWParamChanged();
            app.KernelDropDown_2.ValueChangedFcn     = @(src,evt)app.OnSWParamChanged();
            app.AutoDiffDropDown.ValueChangedFcn     = @(src,evt)app.OnSWParamChanged();
        end

        function OnSWParamChanged(app)
            app.applySWUIToConfig();
        end

        function applySWUIToConfig(app)
            cfg = app.swfgo_config.FGO;

            cfg.window_size = app.parsePositiveInteger( ...
                app.WinSizeEditField_2.Value, 'Win. Size.');
            cfg.max_iteration = app.parsePositiveInteger( ...
                app.MaxIterEditField_2.Value, 'Max Iter.');
            cfg.thres_iteration = app.parsePositiveScalar( ...
                app.ThresIterEditField_2.Value, 'Thres. Iter.');
            cfg.robust_delta = app.parsePositiveScalar( ...
                app.RobustDelEditField_2.Value, 'Robust Del.');
            cfg.P0 = diag(app.parsePositiveDiagonal( ...
                app.InitCovEditField_3.Value, 'Init. Cov.'));
            cfg.Q = diag(app.parsePositiveDiagonal( ...
                app.PropaNoiseEditField_2.Value, 'Propa. Noise.'));
            switch lower(app.KernelDropDown_2.Value)
                case 'huber',  cfg.robust_kernel = 'huber';
                case 'cauchy', cfg.robust_kernel = 'cauchy';
                case 'tukey', cfg.robust_kernel = 'tukey';
                otherwise,     cfg.robust_kernel = 'none';
            end

            cfg.autoDiff = double(strcmpi(app.AutoDiffDropDown.Value, 'Yes'));

            app.swfgo_config.FGO = cfg;
        end

        function OnParamChanged(app, ~)
            % UI -> config
            app.applyUIToConfig();
        end

        function applyUIToConfig(app)
            cfg = app.data.config;


            cfg.KFV.mode = app.mapUIToKfMode(app.KFtypeDropDown.Value);


            switch lower(app.KernelDropDown.Value)
                case 'huber',  cfg.KFV.robust_kernel = 'huber';
                case 'cauchy', cfg.KFV.robust_kernel = 'cauchy';
                case 'tukey',  cfg.KFV.robust_kernel = 'tukey';
                otherwise,     cfg.KFV.robust_kernel = 'none';
            end


            cfg.KFV.robust_delta = app.parsePositiveScalar( ...
                app.RobustDelEditField.Value, 'Robust Del.');
            cfg.KFV.window_size = app.parsePositiveInteger( ...
                app.WinSizeEditField.Value, 'Win. Size.');
            cfg.KFV.max_iteration = app.parsePositiveInteger( ...
                app.MaxIterEditField.Value, 'Max Iter.');
            cfg.KFV.thres_iteration = app.parsePositiveScalar( ...
                app.ThresIterEditField.Value, 'Thres. Iter.');
            cfg.KFV.P0 = diag(app.parsePositiveDiagonal( ...
                app.InitCovEditField_2.Value, 'Init. Cov.'));
            cfg.KFV.Q = diag(app.parsePositiveDiagonal( ...
                app.PropaNoiseEditField.Value, 'Propa. Noise.'));


            app.data.config = cfg;
        end

        function out = mapKfModeToUI(app, mode)
            % mode: 'EKF'|'iEKF'|'rEKF'|'riEKF' -> UI: 'EKF'|'IEKF'|'REKF'
            switch lower(mode)
                case 'iekf', out = 'IEKF';
                case 'rekf', out = 'REKF';
                case 'riekf', out = 'RIEKF';
                otherwise, out = 'EKF';
            end
        end

        function out = mapUIToKfMode(app, uiVal)
            % UI 'EKF'|'IEKF'|'REKF' -> config: 'EKF'|'iEKF'|'rEKF'
            switch upper(uiVal)
                case 'IEKF', out = 'iEKF';
                case 'REKF', out = 'rEKF';
                case 'RIEKF', out = 'riEKF';
                otherwise, out = 'EKF';
            end
        end


        function [positions, errors] = extractPositionsAndErrors(app, result, data)

            if ~isfield(result, 'X')
                error('Result has no field X.');
            end
            X = result.X;
            if size(X,1) < 2
                error('Result.X has insufficient dimension.');
            end
            T = min(size(X,2), size(data.true_positions,2));
            est_xy = X(1:2, 1:T);
            gt_xy  = data.true_positions(:, 1:T);

            positions = est_xy';  % T x 2 for plotting
            errors = sqrt(sum((est_xy - gt_xy).^2, 1))'; % T x 1
        end

        function plotTrajectories(app, data, result1, result2)
            ax = app.UIAxes; cla(ax); hold(ax, 'on');


            if isfield(data, 'emitter_positions') && ~isempty(data.emitter_positions)
                scatter(ax, data.emitter_positions(1,:), data.emitter_positions(2,:), 100, 'k', 'filled');
                legend_entries = {'Anchor Points'};
            else
                legend_entries = {};
            end


            plot(ax, data.true_positions(1,:), data.true_positions(2,:), 'b.-', 'LineWidth', 1.5, 'MarkerSize', 10);
            legend_entries{end+1} = 'True Trajectory';


            plot(ax, result1.X(1,:), result1.X(2,:), 'r.-', 'LineWidth', 1.5, 'MarkerSize', 10);
            legend_entries{end+1} = sprintf('%s traj', app.data.selected_kf);


            plot(ax, result2.X(1,:), result2.X(2,:), 'g.--', 'LineWidth', 1.5, 'MarkerSize', 10);
            legend_entries{end+1} = 'Re-FGO traj';

            legend(ax, legend_entries{:});
            xlabel(ax, 'X Position (m)'); ylabel(ax, 'Y Position (m)');
            title(ax, '2D Trajectory and Anchors'); axis(ax, 'equal');


            try
                xlim(ax, [min(data.true_positions(1,:))-10, max(data.true_positions(1,:))+10]);
                ylim(ax, [min(data.true_positions(2,:))-10, max(data.true_positions(2,:))+10]);
            catch
            end

            hold(ax, 'off');
        end

        function runKFVFGOComparison(app)
            if isempty(app.data.loaded_data)
                error('Please load data first (from config)!');
            end

            % UI -> config
            app.applyUIToConfig();
            cfg  = app.data.config;
            data = app.data.loaded_data;

            app.algo_text.Value = 'Status: Running Analysis...';
            drawnow;

            % ======== KFV ========
            fprintf('Starting KFV analysis...\n');
            tic;
            estimator = KfvEstimator(cfg, data);
            result1   = estimator.run();
            % kfv_time  = toc;

            [kfv_positions, kfv_errors] = app.extractPositionsAndErrors(result1, data);
            app.data.kfv_results = struct('positions', kfv_positions, 'errors', kfv_errors);

            % === KFV time ===
            start_idx = 2;      % from 2nd epoch

            all_time_data = {};
            all_group = {};
            result_kf =result1;
            kf_debug = result_kf.debug_info;
            total_time_kf = [];
            for t = start_idx:length(kf_debug)
                total = 0;
                if isfield(kf_debug{t}, 'prediction_time')
                    total = total + kf_debug{t}.prediction_time;
                end
                if isfield(kf_debug{t}, 'update_time')
                    total = total + kf_debug{t}.update_time;
                end
                total_time_kf(end+1) = total;
            end
            kfv_time = mean(total_time_kf)*1000;  % 转为ms
            fprintf('KFV completed in %.3f ms\n', kfv_time);
            app.processing_time.kfv_time = kfv_time;

            % ======== FGO (template) ========
            fprintf('Starting FGO analysis...\n');

            fgo_template_config = estimator.convert_KFV_config_to_FGO();
            if isfield(fgo_template_config, 'FGO')
                if ~isfield(fgo_template_config.FGO, 'autoDiff'),      fgo_template_config.FGO.autoDiff = 0; end
                % if isfield(fgo_template_config.FGO, 'window_size'),     fgo_template_config.FGO.window_size = cfg.KFV.window_size; end
                % if isfield(fgo_template_config.FGO, 'max_iteration'),   fgo_template_config.FGO.max_iteration = cfg.KFV.max_iteration; end
                % if isfield(fgo_template_config.FGO, 'thres_iteration'), fgo_template_config.FGO.thres_iteration = cfg.KFV.thres_iteration; end
            end
            estimator_fgo = FgoEstimator(fgo_template_config, data);
            tic;
            result2       = estimator_fgo.run();
            % fgo_time      = toc;

            [fgo_positions, fgo_errors] = app.extractPositionsAndErrors(result2, data);
            app.data.fgo_results = struct('positions', fgo_positions, 'errors', fgo_errors);

            % === FGO time ===
            fg_debug = result2.debug_info;
            total_time_fg = [];
            for t = start_idx:length(fg_debug)
                total = 0;
                if isfield(fg_debug{t}, 'add_state_time')
                    total = total + fg_debug{t}.add_state_time;
                end
                if isfield(fg_debug{t}, 'margin_time')
                    if (fg_debug{t}.margin_time >= 0)
                        total = total + fg_debug{t}.margin_time;
                    end
                end
                if isfield(fg_debug{t}, 'ls_time')
                    total = total + fg_debug{t}.ls_time;
                end
                if isfield(fg_debug{t}, 'margin_meas_time')
                    total = total + fg_debug{t}.margin_meas_time;
                end
                total_time_fg(end+1) = total;
            end

            fgo_time = mean(total_time_fg)*1000;  %  to ms
            fprintf('FGO completed in %.3f seconds\n', fgo_time);
            app.processing_time.fgo_time = fgo_time;

            % ======== Visualization ========
            app.data.cpu_usage = [app.processing_time.kfv_time, app.processing_time.fgo_time, 1, 5];
            app.plotTrajectories(data, result1, result2);
            app.updateRightPanels();

            % ======== Dashboard ========
            app.updateDashboard(kfv_errors, fgo_errors);
            app.algo_text.Value = sprintf(['Analysis Complete\nAlgorithm: %s & FGO\n' ...
                'KFV RMSE: %.3f m (%.3f ms/epoch)\nFGO RMSE: %.3f m (%.3f ms/epoch)'], ...
                app.data.selected_kf, sqrt(mean(kfv_errors.^2)), kfv_time, ...
                sqrt(mean(fgo_errors.^2)), fgo_time);
            drawnow;
        end

        function runSWFGO(app)

            app.applySWUIToConfig();
            cfg = app.swfgo_config;


            if ~isfield(cfg, 'data') || ~isfield(cfg.data, 'path') || isempty(cfg.data.path)
                error('SWFGO config.data.path is empty.');
            end
            dataPath = app.resolveDataPath(cfg.data.path);
            data = validate_comparison_data(load(dataPath));


            app.algo_text.Value = 'Status: Running SW-FGO...'; drawnow;
            fprintf('Starting SW-FGO analysis...\n');
            tic;
            estimator_fgo = FgoEstimator(cfg, data);
            result        = estimator_fgo.run();
            elapsed       = toc;

            % === FGO time ===
            start_idx = 2;      % 从第2帧开始计时
            fg_debug = result.debug_info;
            total_time_fg = [];
            for t = start_idx:length(fg_debug)
                total = 0;
                if isfield(fg_debug{t}, 'add_state_time')
                    total = total + fg_debug{t}.add_state_time;
                end
                if isfield(fg_debug{t}, 'margin_time')
                    if (fg_debug{t}.margin_time >= 0)
                        total = total + fg_debug{t}.margin_time;
                    end
                end
                if isfield(fg_debug{t}, 'ls_time')
                    total = total + fg_debug{t}.ls_time;
                end
                if isfield(fg_debug{t}, 'margin_meas_time')
                    total = total + fg_debug{t}.margin_meas_time;
                end
                total_time_fg(end+1) = total;
            end

            fgo_time = mean(total_time_fg)*1000;  % to ms
            % fprintf('FGO completed in %.3f seconds\n', fgo_time);
            app.processing_time.fgo_time = fgo_time;

            fprintf('SW-FGO mean epoch time: %.3f ms\n', fgo_time);

            X = result.X;
            T = min(size(X,2), size(data.true_positions,2));
            est_xy = X(1:2,1:T);
            gt_xy  = data.true_positions(:,1:T);
            errors = sqrt(sum((est_xy - gt_xy).^2, 1))';


            app.data.kfv_results = [];
            app.data.fgo_results = struct('positions', est_xy', 'errors', errors);


            app.processing_time.kfv_time = 0;
            % app.processing_time.fgo_time = elapsed;


            ax = app.UIAxes; cla(ax); hold(ax,'on');
            if isfield(data,'emitter_positions') && ~isempty(data.emitter_positions)
                scatter(ax, data.emitter_positions(1,:), data.emitter_positions(2,:), 100, 'k', 'filled');
                lgd = {'Anchor Points'};
            else
                lgd = {};
            end
            plot(ax, data.true_positions(1,:), data.true_positions(2,:), 'b.-', 'LineWidth', 1.5, 'MarkerSize', 8);
            lgd{end+1} = 'True Trajectory';
            plot(ax, est_xy(1,:), est_xy(2,:), 'g.--', 'LineWidth', 1.5, 'MarkerSize', 6);
            lgd{end+1} = 'SW-FGO traj';
            legend(ax, lgd{:});
            xlabel(ax,'X Position (m)'); ylabel(ax,'Y Position (m)');
            title(ax,'2D PDR Trajectory and Anchors'); axis(ax,'equal');
            hold(ax, 'off');


            app.updateRightPanels();


            rmse = sqrt(mean(errors.^2));
            app.perf_text.Value = sprintf('RMSE (SW-FGO): %.3f m\nElapsed: %.3f s\nWindow: %s', ...
                rmse, elapsed, num2str(cfg.FGO.window_size));
            autoDiffLabel = 'No';
            if logical(cfg.FGO.autoDiff), autoDiffLabel = 'Yes'; end
            app.algo_text.Value = sprintf([ ...
                'SW-FGO Complete\nKernel: %s, MaxIter: %s\n' ...
                'AutoDiff: %s\nRMSE: %.3f m (%.3fs)'], ...
                cfg.FGO.robust_kernel, num2str(cfg.FGO.max_iteration), ...
                autoDiffLabel, rmse, elapsed);
            app.time_text.Value = sprintf( ...
                'Mean FGO epoch: %.3f ms\nTotal run: %.3f s', fgo_time, elapsed);
        end

        function saveParameters(app)

            params = struct();


            params.kfv.selected_kf = app.KFtypeDropDown.Value;
            params.kfv.kernel = app.KernelDropDown.Value;
            params.kfv.robust_del = app.RobustDelEditField.Value;
            params.kfv.win_size = app.WinSizeEditField.Value;
            params.kfv.max_iter = app.MaxIterEditField.Value;
            params.kfv.thres_iter = app.ThresIterEditField.Value;
            params.kfv.init_cov = app.InitCovEditField_2.Value;
            params.kfv.propa_noise = app.PropaNoiseEditField.Value;


            params.swfgo.kernel = app.KernelDropDown_2.Value;
            params.swfgo.robust_del = app.RobustDelEditField_2.Value;
            params.swfgo.win_size = app.WinSizeEditField_2.Value;
            params.swfgo.max_iter = app.MaxIterEditField_2.Value;
            params.swfgo.thres_iter = app.ThresIterEditField_2.Value;
            params.swfgo.init_cov = app.InitCovEditField_3.Value;
            params.swfgo.propa_noise = app.PropaNoiseEditField_2.Value;


            [filename, pathname] = uiputfile('*.mat', 'Save Parameters As');
            if ~isequal(filename, 0)
                save(fullfile(pathname, filename), 'params');


                app.algo_text.Value = sprintf('%s\n\nParameters Saved Successfully!', app.algo_text.Value);


                timer_obj = timer('ExecutionMode', 'singleShot', 'StartDelay', 3, ...
                    'TimerFcn', @(~,~) app.clearSaveMessage());
                start(timer_obj);
            end
        end

        function loadParameters(app)
            [filename, pathname] = uigetfile('*.mat', 'Load Parameters');
            if ~isequal(filename, 0)
                try
                    loaded = load(fullfile(pathname, filename));
                    params = loaded.params;


                    app.KFtypeDropDown.Value = params.kfv.selected_kf;
                    app.KernelDropDown.Value = params.kfv.kernel;
                    app.RobustDelEditField.Value = params.kfv.robust_del;
                    app.WinSizeEditField.Value = params.kfv.win_size;
                    app.MaxIterEditField.Value = params.kfv.max_iter;
                    app.ThresIterEditField.Value = params.kfv.thres_iter;
                    app.InitCovEditField_2.Value = params.kfv.init_cov;
                    app.PropaNoiseEditField.Value = params.kfv.propa_noise;


                    app.KernelDropDown_2.Value = params.swfgo.kernel;
                    app.RobustDelEditField_2.Value = params.swfgo.robust_del;
                    app.WinSizeEditField_2.Value = params.swfgo.win_size;
                    app.MaxIterEditField_2.Value = params.swfgo.max_iter;
                    app.ThresIterEditField_2.Value = params.swfgo.thres_iter;
                    app.InitCovEditField_3.Value = params.swfgo.init_cov;
                    app.PropaNoiseEditField_2.Value = params.swfgo.propa_noise;

                    app.algo_text.Value = 'Parameters Loaded Successfully!';
                catch ME
                    uialert(app.FactorGraphOptimizationSimulatorUIFigure, ...
                        ['Error loading parameters: ' ME.message], 'Load Error');
                end
            end
        end

        function clearSaveMessage(app)

            current_text = app.algo_text.Value;
            lines = strsplit(current_text, '\n');

            filtered_lines = lines(~contains(lines, 'Parameters Saved'));
            app.algo_text.Value = strjoin(filtered_lines, '\n');
        end

        function updateParameterAccessibility(app)

            currentTab = app.TabGroup.SelectedTab;

            if isequal(currentTab, app.ComparisonbetweenKFVandFGOTab)
                app.updateKFVParameterAccessibility();
            elseif isequal(currentTab, app.SWFGOSimulationTab)
                app.updateSWFGOParameterAccessibility();
            end
        end
        function updateKFVParameterAccessibility(app)
            % Update parameter accessibility according to the selected KF type
            selected_kf = app.KFtypeDropDown.Value;

            % First, enable all parameters (white background)
            app.enableAllKFVParams();

            % Disable specific parameters depending on the filter type
            switch selected_kf
                % case 'KF'
                %     % Standard KF: window size fixed to 1, other advanced parameters unavailable
                %     app.disableKFVParam(app.WinSizeEditField, '1');
                %     app.disableKFVParam(app.MaxIterEditField, '1');
                %     app.disableKFVParam(app.ThresIterEditField, '1E-6');
                %     app.disableKFVParam(app.RobustDelEditField, '2');
                %     app.disableKFVDropdown(app.KernelDropDown, 'None');
                %
                case 'EKF'
                    % EKF: window size fixed to 1, iteration and robust parameters not supported
                    app.disableKFVParam(app.WinSizeEditField, '1');
                    app.disableKFVParam(app.MaxIterEditField, '1');
                    app.disableKFVParam(app.ThresIterEditField, '1E-6');
                    app.disableKFVParam(app.RobustDelEditField, '2');
                    app.disableKFVDropdown(app.KernelDropDown, 'None');

                case 'IEKF'
                    % IEKF: window size fixed to 1, robust parameters not supported
                    app.disableKFVParam(app.WinSizeEditField, '1');
                    app.disableKFVParam(app.RobustDelEditField, '2');
                    app.disableKFVDropdown(app.KernelDropDown, 'None');

                case 'REKF'
                    % REKF: window size fixed to 1, iteration threshold and kernel not supported
                    app.disableKFVParam(app.WinSizeEditField, '1');
                    app.disableKFVParam(app.MaxIterEditField, '1');
                    app.disableKFVParam(app.ThresIterEditField, '1E-6');
                    % app.disableKFVParam(app.RobustDelEditField, 'None');
                    % app.disableKFVDropdown(app.KernelDropDown, 'None');

                case {'RIEKF'}
                    % RIEKF: supports all parameters
                    % Window size fixed to 1 (these are single-point filters)
                    app.disableKFVParam(app.WinSizeEditField, '1');
                    % app.disableKFVParam(app.RobustDelEditField, 'None');
                    % app.disableKFVDropdown(app.KernelDropDown, 'None');
                    %
                otherwise
                    % Default: enable all parameters
                    app.enableAllKFVParams();
            end
        end

        function disableKFVParam(app, control, defaultValue)

            control.Enable = 'off';
            control.BackgroundColor = [0.94 0.94 0.94];
            if nargin > 2
                control.Value = defaultValue;
            end
        end

        function disableKFVDropdown(app, dropdown, defaultValue)

            dropdown.Enable = 'off';
            dropdown.BackgroundColor = [0.94 0.94 0.94]; %
            if nargin > 2
                dropdown.Value = defaultValue;
            end
        end

        function enableKFVParam(app, control)

            control.Enable = 'on';
            control.BackgroundColor = [1 1 1]; %
        end

        function enableKFVDropdown(app, dropdown)
            %
            dropdown.Enable = 'on';
            dropdown.BackgroundColor = [1 1 1]; %
        end

        function updateSWFGOParameterAccessibility(app)

            app.WinSizeEditField_2.Enable = 'on';
            app.WinSizeEditField_2.BackgroundColor = [1 1 1];


            app.enableAllSWFGOParams();
        end

        function enableAllKFVParams(app)
            % enable all KFV params
            controls = {app.WinSizeEditField, app.MaxIterEditField, app.ThresIterEditField, ...
                app.RobustDelEditField, app.InitCovEditField_2, app.PropaNoiseEditField};
            dropdowns = {app.KernelDropDown};

            for i = 1:length(controls)
                app.enableKFVParam(controls{i});
            end

            for i = 1:length(dropdowns)
                app.enableKFVDropdown(dropdowns{i});
            end
        end

        function enableAllOtherKFVParams(app)

            controls = {app.MaxIterEditField, app.ThresIterEditField, ...
                app.RobustDelEditField, app.InitCovEditField_2, app.PropaNoiseEditField};
            dropdowns = {app.KernelDropDown};

            for i = 1:length(controls)
                app.enableKFVParam(controls{i});
            end

            for i = 1:length(dropdowns)
                app.enableKFVDropdown(dropdowns{i});
            end
        end

        function enableAllSWFGOParams(app)

            controls = {app.WinSizeEditField_2, app.MaxIterEditField_2, app.ThresIterEditField_2, ...
                app.RobustDelEditField_2, app.InitCovEditField_3, app.PropaNoiseEditField_2};
            dropdowns = {app.KernelDropDown_2};

            for i = 1:length(controls)
                controls{i}.Enable = 'on';
                controls{i}.BackgroundColor = [1 1 1];
            end

            for i = 1:length(dropdowns)
                dropdowns{i}.Enable = 'on';
                dropdowns{i}.BackgroundColor = [1 1 1];
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            addpath(fullfile(fileparts(mfilename('fullpath')), '..', 'config'));
            addpath(fullfile(fileparts(mfilename('fullpath')), '..', 'core'));
            addpath(fullfile(fileparts(mfilename('fullpath')), '..', 'core','estimator'));
            addpath(fullfile(fileparts(mfilename('fullpath')), '..', 'core','filter'));
            addpath(fullfile(fileparts(mfilename('fullpath')), '..', 'core','fgo'));
            addpath(fullfile(fileparts(mfilename('fullpath')), '..', 'core','fgo','factor'));
            addpath(fullfile(fileparts(mfilename('fullpath')), '..', 'data'));


            app.data.selected_kf = app.KFtypeDropDown.Value;


            app.updateRightPanels();


            algo_text = app.algo_text;
            perf_text = app.perf_text;
            time_text = app.time_text;

            %             if ~isempty(status_text)
            %                 set(status_text, 'String', 'System Status: Running Analysis...');
            %             end

            if ~isempty(algo_text)
                set(algo_text, 'Value', sprintf('Algorithm: %s\nMode: %s\nIterations: %d\nConvergence: %.6f', ...
                    'KFV', 'iEKF', 0, 0));
            end


            set(perf_text, 'Value', sprintf([ ...
                'RMSE KFV: %.3f m\nRMSE FGO: %.3f m\n' ...
                'Illustrative single-run timing; not a benchmark.'], 0, 0));


            if ~isempty(time_text)
                set(time_text, 'Value', sprintf( ...
                    'Mean KFV epoch: %.3f ms\nMean FGO epoch: %.3f ms', 0, 0));
            end


            app.loadConfigFromScript();
            app.populateUIFromConfig();

            %
            try
                app.loadSWFGOConfigFromScript();
                app.populateSWUIFromConfig();
            catch ME
                warning('KFVFGO:SWFGOConfigLoadFailed', '%s', ME.message);
            end


            try
                app.loadDataFromConfig();
            catch ME
                warning('KFVFGO:AutoDataLoadFailed', '%s', ME.message);
            end


            app.algo_text.Value = sprintf('Algorithm: %s\nMode: %s\nIterations: %d\nConvergence: %.6f', ...
                'KFV', app.data.config.KFV.mode, 0, 0);
            app.perf_text.Value = sprintf([ ...
                'RMSE KFV: %.3f m\nRMSE FGO: %.3f m\n' ...
                'Illustrative single-run timing; not a benchmark.'], 0, 0);
            app.time_text.Value = sprintf( ...
                'Mean KFV epoch: %.3f ms\nMean FGO epoch: %.3f ms', 0, 0);


            app.updateRightPanels();


            app.updateParameterAccessibility();
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)

            [filename, pathname] = uigetfile({'*.mat', 'MAT-files (*.mat)'}, 'Select a MAT file');

            if isequal(filename, 0) || isequal(pathname, 0)
                disp('User canceled file selection');
                return;
            end


            full_path = fullfile(pathname, filename);
            try
                app.data.loaded_data = validate_comparison_data(load(full_path));

                disp(['Successfully loaded data from: ' full_path]);
            catch ME
                errordlg(['Error loading file: ' ME.message], 'File Load Error');
            end

            app.algo_text.Value = sprintf('Selected KF: %s\nStatus: Data Loaded\nData: %s', ...
                app.data.selected_kf, full_path);
        end

        % Callback function
        function Button_3Pushed(app, event)
            disp('Simulation paused');
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            if app.is_running
                return;
            end
            try
                app.is_running = true;
                runCleanup = onCleanup(@() app.releaseRunLock());

                if isequal(app.TabGroup.SelectedTab, app.ComparisonbetweenKFVandFGOTab)

                    app.runKFVFGOComparison();

                elseif isequal(app.TabGroup.SelectedTab, app.SWFGOSimulationTab)

                    app.runSWFGO();

                else
                    uialert(app.FactorGraphOptimizationSimulatorUIFigure, ...
                        'The current tab is not recognized. Please switch to the "KFV+FGO" or "SW-FGO" page before running.', ...
                        'Tips', 'Icon', 'warning');
                end

            catch ME
                app.algo_text.Value = sprintf('Run failed\n%s', ME.message);
                uialert(app.FactorGraphOptimizationSimulatorUIFigure, ...
                    ME.message, 'Run Error', 'Icon', 'error', 'Modal', false);
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            msg = sprintf([ ...
                'Hello! 👋\n\n', ...
                'This is a GUI of a comparison between Kalman Filter with Its Variants and Factor Graph Optimization.\n\n', ...
                'Instructions:\n', ...
                '1) Please click the "📁" button on the left to load the data.\n', ...
                '2) Adjust the parameters in the corresponding tab in the lower left corner.\n', ...
                '3) Click the green "▶ Run" button to run the process corresponding to the current tab and view the results in the main graph, CDF, and CPU usage.\n\n', ...
                'Hope you enjoy using it :-D！' ...
                ]);
            uialert(app.FactorGraphOptimizationSimulatorUIFigure, msg, 'Hi there!', 'Icon', 'info');
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            choice = questdlg('Are you sure you want to delete current data?', ...
                'Delete Confirmation', ...
                'Yes', 'No', 'No');
            if strcmp(choice, 'Yes')
                app.data.loaded_data = [];
                app.data.kfv_results = [];
                app.data.fgo_results = [];
                app.data.cpu_usage = [];
                app.processing_time = struct('kfv_time', 0, 'fgo_time', 0);
                app.swfgo_results = [];
                cla(app.UIAxes);
                cla(app.CDFAxes);
                cla(app.TimeAxes);
                cla(app.ErrorSeriesAxes);
                app.algo_text.Value = sprintf( ...
                    'Selected KF: %s\nStatus: Ready\nData: Not Loaded', ...
                    app.data.selected_kf);
                app.perf_text.Value = sprintf( ...
                    'RMSE KFV: %.3f m\nRMSE FGO: %.3f m', 0, 0);
                app.time_text.Value = sprintf( ...
                    'Mean KFV epoch: %.3f ms\nMean FGO epoch: %.3f ms', 0, 0);
            end
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)

            choice = uiconfirm(app.FactorGraphOptimizationSimulatorUIFigure, ...
                'Choose an action:', 'Settings', ...
                'Options', {'Save Parameters', 'Load Parameters', 'Cancel'}, ...
                'DefaultOption', 1);

            switch choice
                case 'Save Parameters'
                    app.saveParameters();
                case 'Load Parameters'
                    app.loadParameters();
                case 'Cancel'
                    return;
            end
        end

        % Callback function
        function SimulationModeButtonPushed(app, event)
            app.SimulationModeLabel.Visible="on";
            app.Panel.Visible="on";
            app.FileModeLabel.Visible="off";
            app.Panel_2.Visible="off";
        end

        % Callback function
        function RealworldModeButtonPushed(app, event)
            app.SimulationModeLabel.Visible="off";
            app.Panel.Visible="off";
            app.FileModeLabel.Visible="on";
            app.Panel_2.Visible="on";
        end

        % Callback function
        function SelectKFDropDownOpening(app, event)
            selected_kf = app.KFtypeDropDown.Value;


            app.data.selected_kf = selected_kf;


            fprintf('KF algorithm changed to: %s\n', selected_kf);
        end

        % Drop down opening function: KFtypeDropDown
        function KFtypeDropDownValueChanged(app, event)
            selected_kf = app.KFtypeDropDown.Value;
            app.data.selected_kf = selected_kf;


            app.updateParameterAccessibility();


            app.applyUIToConfig();


            if isempty(app.data.loaded_data), data_status = 'Not Loaded'; else, data_status = 'Loaded'; end
            app.algo_text.Value = sprintf('Selected KF: %s\nStatus: Ready\nData: %s', ...
                selected_kf, data_status);

            fprintf('KF algorithm changed to: %s\n', selected_kf);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            app.updateParameterAccessibility();

            selectedTab = app.TabGroup.SelectedTab;
            if app.is_running
                return;
            end
            try
                app.is_running = true;

                newTab = event.NewValue;

                if isequal(newTab, app.ComparisonbetweenKFVandFGOTab)

                    if ~isfield(app.data, 'config') || isempty(app.data.config)
                        app.loadConfigFromScript();
                        app.populateUIFromConfig();
                    end
                    if ~isfield(app.data, 'loaded_data') || isempty(app.data.loaded_data)

                        app.loadDataFromConfig();
                    end

                    app.runKFVFGOComparison();

                elseif isequal(newTab, app.SWFGOSimulationTab)

                    if isempty(app.swfgo_config)
                        app.loadSWFGOConfigFromScript();
                        app.populateSWUIFromConfig();
                    end
                    % run SW-FGO
                    app.runSWFGO();
                end

            catch ME
                errordlg(['Tab switch run failed: ' ME.message], 'Run Error');
            end
            app.is_running = false;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FactorGraphOptimizationSimulatorUIFigure = uifigure('Visible', 'off');
            app.FactorGraphOptimizationSimulatorUIFigure.Name = 'Factor Graph Optimization Simulator';
            app.Panel = uipanel(app.FactorGraphOptimizationSimulatorUIFigure);
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, '2D PDR Trajectory and Anchors')
            xlabel(app.UIAxes, 'X Position (m)')
            ylabel(app.UIAxes, 'Y Position (m)')
            zlabel(app.UIAxes, 'Z')
            app.CDFAxes = uiaxes(app.Panel);
            title(app.CDFAxes, 'CDF Comparison')
            app.TimeAxes = uiaxes(app.Panel);
            title(app.TimeAxes, 'Processing Time')
            app.ErrorSeriesAxes = uiaxes(app.Panel);
            title(app.ErrorSeriesAxes, 'Error Time Series')
            xlabel(app.ErrorSeriesAxes, 'Time Step')
            ylabel(app.ErrorSeriesAxes, 'Error (m)')
            app.Button = uibutton(app.Panel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '?';
            app.Button_2 = uibutton(app.Panel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '📁';
            app.Button_4 = uibutton(app.Panel, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '▶';
            app.Button_5 = uibutton(app.Panel, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '🗑';
            app.Button_6 = uibutton(app.Panel, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '⚙';
            app.DashboardPanel = uipanel(app.Panel);
            app.DashboardPanel.Title = 'Dashboard';
            app.algo_text = uitextarea(app.DashboardPanel);
            app.perf_text = uitextarea(app.DashboardPanel);
            app.time_text = uitextarea(app.DashboardPanel);
            app.TabGroup = uitabgroup(app.Panel);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.ComparisonbetweenKFVandFGOTab = uitab(app.TabGroup);
            app.ComparisonbetweenKFVandFGOTab.Title = 'Comparison between KFV and FGO';
            app.KFVariantsLabel = uilabel(app.ComparisonbetweenKFVandFGOTab);
            app.KFVariantsLabel.Text = 'KF Variants';
            app.Panel_5 = uipanel(app.ComparisonbetweenKFVandFGOTab);
            app.KFtypeDropDownLabel = uilabel(app.Panel_5);
            app.KFtypeDropDownLabel.Text = 'KF type:';
            app.KFtypeDropDown = uidropdown(app.Panel_5);
            app.KFtypeDropDown.Items = {'EKF', 'REKF', 'IEKF', 'RIEKF'};
            app.KFtypeDropDown.ValueChangedFcn = createCallbackFcn(app, @KFtypeDropDownValueChanged, true);
            app.KFtypeDropDown.Value = 'EKF';
            app.InitCovEditField_2Label = uilabel(app.Panel_5);
            app.InitCovEditField_2Label.Text = 'Init. Cov.';
            app.InitCovEditField_2 = uieditfield(app.Panel_5, 'text');
            app.InitCovEditField_2.Value = '[50, 50, 1, 1]';
            app.PropaNoiseEditFieldLabel = uilabel(app.Panel_5);
            app.PropaNoiseEditFieldLabel.Text = 'Propa. Noise';
            app.PropaNoiseEditField = uieditfield(app.Panel_5, 'text');
            app.PropaNoiseEditField.Value = '[1e-4, 1e-4, 1e-4, 1e-4]';
            app.RobustDelLabel = uilabel(app.Panel_5);
            app.RobustDelLabel.Text = 'Robust Del.';
            app.RobustDelEditField = uieditfield(app.Panel_5, 'text');
            app.RobustDelEditField.Value = '2';
            app.WinSizeEditFieldLabel = uilabel(app.Panel_5);
            app.WinSizeEditFieldLabel.Text = 'Win. Size';
            app.WinSizeEditField = uieditfield(app.Panel_5, 'text');
            app.WinSizeEditField.Value = '1';
            app.MaxIterEditFieldLabel = uilabel(app.Panel_5);
            app.MaxIterEditFieldLabel.Text = 'Max Iter.';
            app.MaxIterEditField = uieditfield(app.Panel_5, 'text');
            app.MaxIterEditField.Value = '2';
            app.ThresIterLabel = uilabel(app.Panel_5);
            app.ThresIterLabel.Text = 'Thres. Iter.';
            app.ThresIterEditField = uieditfield(app.Panel_5, 'text');
            app.ThresIterEditField.Value = '1E-6';
            app.KernelDropDownLabel = uilabel(app.Panel_5);
            app.KernelDropDownLabel.Text = 'Kernel';
            app.KernelDropDown = uidropdown(app.Panel_5);
            app.KernelDropDown.Items = {'Huber', 'None'};
            app.KernelDropDown.Value = 'Huber';
            app.SWFGOSimulationTab = uitab(app.TabGroup);
            app.SWFGOSimulationTab.Title = 'SW FGO Simulation';
            app.SWFGOLabel = uilabel(app.SWFGOSimulationTab);
            app.SWFGOLabel.Text = 'SW FGO';
            app.Panel_6 = uipanel(app.SWFGOSimulationTab);
            app.InitCovEditField_3Label = uilabel(app.Panel_6);
            app.InitCovEditField_3Label.Text = 'Init. Cov.';
            app.InitCovEditField_3 = uieditfield(app.Panel_6, 'text');
            app.InitCovEditField_3.Value = '[50, 50, 1, 1]';
            app.PropaNoiseEditField_2Label = uilabel(app.Panel_6);
            app.PropaNoiseEditField_2Label.Text = 'Propa. Noise';
            app.PropaNoiseEditField_2 = uieditfield(app.Panel_6, 'text');
            app.PropaNoiseEditField_2.Value = '[1e-4, 1e-4, 1e-4, 1e-4]';
            app.KernelDropDown_2Label = uilabel(app.Panel_6);
            app.KernelDropDown_2Label.Text = 'Kernel';
            app.KernelDropDown_2 = uidropdown(app.Panel_6);
            app.KernelDropDown_2.Items = {'Huber', 'None', 'Cauchy'};
            app.KernelDropDown_2.Value = 'Huber';
            app.RobustDelEditField_2Label = uilabel(app.Panel_6);
            app.RobustDelEditField_2Label.Text = 'Robust Del.';
            app.RobustDelEditField_2 = uieditfield(app.Panel_6, 'text');
            app.RobustDelEditField_2.Value = '2';
            app.WinSizeEditField_2Label = uilabel(app.Panel_6);
            app.WinSizeEditField_2Label.Text = 'Win. Size';
            app.WinSizeEditField_2 = uieditfield(app.Panel_6, 'text');
            app.WinSizeEditField_2.Value = '4';
            app.MaxIterEditField_2Label = uilabel(app.Panel_6);
            app.MaxIterEditField_2Label.Text = 'Max Iter.';
            app.MaxIterEditField_2 = uieditfield(app.Panel_6, 'text');
            app.MaxIterEditField_2.Value = '1';
            app.ThresIterEditField_2Label = uilabel(app.Panel_6);
            app.ThresIterEditField_2Label.Text = 'Thres. Iter.';
            app.ThresIterEditField_2 = uieditfield(app.Panel_6, 'text');
            app.ThresIterEditField_2.Value = '1E-6';
            app.AutoDiffDropDownLabel = uilabel(app.Panel_6);
            app.AutoDiffDropDownLabel.Text = 'AutoDiff';
            app.AutoDiffDropDown = uidropdown(app.Panel_6);
            app.AutoDiffDropDown.Items = {'Yes', 'No'};
            app.AutoDiffDropDown.Value = 'Yes';
            % Show the figure after all components are created
            app.FactorGraphOptimizationSimulatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FGO_KF_Simulator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FactorGraphOptimizationSimulatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FactorGraphOptimizationSimulatorUIFigure)
        end
    end
end