classdef FGO_KF_Simulator0617 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FactorGraphOptimizationSimulatorUIFigure  matlab.ui.Figure
        SimulationModeLabel         matlab.ui.control.Label
        RealworldModeButton         matlab.ui.control.Button
        SimulationModeButton        matlab.ui.control.Button
        FileModeLabel               matlab.ui.control.Label
        Panel                       matlab.ui.container.Panel
        DashboardPanel              matlab.ui.container.Panel
        time_text                   matlab.ui.control.TextArea
        perf_text                   matlab.ui.control.TextArea
        algo_text                   matlab.ui.control.TextArea
        Panel_5                     matlab.ui.container.Panel
        SelectKFDropDown            matlab.ui.control.DropDown
        SelectKFDropDownLabel       matlab.ui.control.Label
        SWEKFCheckBox               matlab.ui.control.CheckBox
        RTSSCheckBox                matlab.ui.control.CheckBox
        REKFCheckBox                matlab.ui.control.CheckBox
        MIEKFCheckBox               matlab.ui.control.CheckBox
        IEKFCheckBox                matlab.ui.control.CheckBox
        EKFCheckBox                 matlab.ui.control.CheckBox
        KFCheckBox                  matlab.ui.control.CheckBox
        KFVariantsLabel             matlab.ui.control.Label
        Panel_4                     matlab.ui.container.Panel
        KernelDropDown              matlab.ui.control.DropDown
        KernelDropDownLabel         matlab.ui.control.Label
        GaussianmodelDropDown       matlab.ui.control.DropDown
        GaussianmodelDropDownLabel  matlab.ui.control.Label
        NoiseLabel                  matlab.ui.control.Label
        Button_6                    matlab.ui.control.Button
        Button_5                    matlab.ui.control.Button
        Button_4                    matlab.ui.control.Button
        Button_3                    matlab.ui.control.Button
        Button_2                    matlab.ui.control.Button
        Button                      matlab.ui.control.Button
        FGOAxes                     matlab.ui.control.UIAxes
        KFVAxes                     matlab.ui.control.UIAxes
        CPUAxes                     matlab.ui.control.UIAxes
        ErrorAxes                   matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
        Panel_2                     matlab.ui.container.Panel
    end

    
    properties (Access = private)
        data = struct('current_mode', 'beginner', 'loaded_data', [], ...
                    'kfv_results', [], 'fgo_results', [], 'cpu_usage', [], ...
                    'selected_kf', 'KF', 'kfv_estimator', [])  % 添加新的属性
        processing_time = struct('kfv_time', 0, 'fgo_time', 0)  % 记录处理时间
    end
    
    methods (Access = private)
        

        % Kalman Filter函数
        function [X_est, P_est, X_pred, P_pred] = kf(app, X_est_prev, P_est_prev, F, Q, B, U, Z, H, R)
            % 一步预测
            X_pred = F * X_est_prev + B * U;
            P_pred = F * P_est_prev * F' + Q;
            
            % 测量更新
            K = P_pred * H' / (H * P_pred * H' + R);
            X_est = X_pred + K * (Z - H * X_pred);
            P_est = (eye(size(K, 1)) - K * H) * P_pred;
        end
        
        function updateRightPanels(app)
            % 更新右侧所有图表
            error_axes = app.ErrorAxes;
            cpu_axes = app.CPUAxes;
            kfv_axes = app.KFVAxes;
            fgo_axes = app.FGOAxes;
            
            if ~isempty(error_axes)
                app.createAlgorithmErrorPlot(error_axes);
            end
            
            if ~isempty(cpu_axes)
                app.createCPUUsagePlot(cpu_axes);
            end
            
            if ~isempty(kfv_axes)
                app.createKFVStatistics(kfv_axes);
            end
            
            if ~isempty(fgo_axes)
                app.createFGOStatistics(fgo_axes);
            end
        end
        % 新增绘图函数
        function createAlgorithmErrorPlot(app, ax)
            % 创建KFV和FGO的误差箱线图
            data = app.data;
            
            if isempty(data.kfv_results) || isempty(data.fgo_results)
                % 默认示例数据
                kfv_errors = randn(100, 1) * 2 + 3;
                fgo_errors = randn(100, 1) * 1.5 + 2;
            else
                kfv_errors = data.kfv_results.errors;
                fgo_errors = data.fgo_results.errors;
            end
            
            boxplot(ax, [kfv_errors; fgo_errors], [ones(length(kfv_errors), 1); 2*ones(length(fgo_errors), 1)]);
            set(ax, 'XTickLabel', {'KFV', 'FGO'});
            title(ax, 'Algorithm Error Comparison');
            ylabel(ax, 'Error (m)');
            grid(ax, 'on');
        end
        
        function createCPUUsagePlot(app, ax)
            % 创建CPU使用率饼图
            data = app.data;
            
            if isempty(data.cpu_usage)
                % 默认CPU使用率数据
                cpu_data = [25, 15, 10, 50]; % KFV, FGO, System, Idle
                labels = {'KFV 25%', 'FGO 15%', 'System 10%', 'Idle 50%'};
            else
                cpu_data = data.cpu_usage;
                labels = {'KFV', 'FGO', 'System', 'Idle'};
            end
            
            colors = [0.298, 0.686, 0.314;   % 绿色 - KFV
                      0.957, 0.263, 0.212;   % 红色 - FGO
                      0.129, 0.588, 0.953;   % 蓝色 - System
                      0.8, 0.8, 0.8];        % 灰色 - Idle
                  
            h = pie(ax, cpu_data);
            title(ax, 'CPU Usage Distribution');
            
            % 设置颜色
            for i = 1:length(cpu_data)
                set(h(2*i-1), 'FaceColor', colors(i,:));
            end
        end
        
        function createKFVStatistics(app, ax)
            % 创建KFV误差CDF图
            cla(ax);
            
            if ~isempty(app.data.kfv_results)
                errors = app.data.kfv_results.errors;
                
                % 计算CDF
                [f, x] = ecdf(errors);
                
                % 绘制CDF
                plot(ax, x, f, 'b-', 'LineWidth', 2);
                xlabel(ax, 'Error (m)');
                ylabel(ax, 'Cumulative Probability');
                title(ax, sprintf('%s Error CDF', app.data.selected_kf));
                grid(ax, 'on');
                
                % 添加统计信息
                rmse = sqrt(mean(errors.^2));
                p50 = prctile(errors, 50);
                p95 = prctile(errors, 95);
                
                text(ax, 0.6, 0.3, sprintf('RMSE: %.3f m\n50%%: %.3f m\n95%%: %.3f m', ...
                    rmse, p50, p95), 'Units', 'normalized', 'FontSize', 9, ...
                    'BackgroundColor', 'white', 'EdgeColor', 'black');
            else
                text(ax, 0.5, 0.5, 'No KFV Data', 'HorizontalAlignment', 'center', ...
                    'Units', 'normalized', 'FontSize', 12);
                title(ax, sprintf('%s Error CDF', app.data.selected_kf));
            end
        end
        
        function createFGOStatistics(app, ax)
            % 创建FGO误差CDF图
            cla(ax);
            
            if ~isempty(app.data.fgo_results)
                errors = app.data.fgo_results.errors;
                
                % 计算CDF
                [f, x] = ecdf(errors);
                
                % 绘制CDF
                plot(ax, x, f, 'r-', 'LineWidth', 2);
                xlabel(ax, 'Error (m)');
                ylabel(ax, 'Cumulative Probability');
                title(ax, 'FGO Error CDF');
                grid(ax, 'on');
                
                % 添加统计信息
                rmse = sqrt(mean(errors.^2));
                p50 = prctile(errors, 50);
                p95 = prctile(errors, 95);
                
                text(ax, 0.6, 0.3, sprintf('RMSE: %.3f m\n50%%: %.3f m\n95%%: %.3f m', ...
                    rmse, p50, p95), 'Units', 'normalized', 'FontSize', 9, ...
                    'BackgroundColor', 'white', 'EdgeColor', 'black');
            else
                text(ax, 0.5, 0.5, 'No FGO Data', 'HorizontalAlignment', 'center', ...
                    'Units', 'normalized', 'FontSize', 12);
                title(ax, 'FGO Error CDF');
            end
        end
        
        function updateDashboard(app, kfv_results, fgo_results)
            % 更新Dashboard显示信息
%             status_text = app.StatusText;
            algo_text = app.algo_text;
            perf_text = app.perf_text;
            time_text = app.time_text;
            
%             if ~isempty(status_text)
%                 set(status_text, 'String', 'System Status: Running Analysis...');
%             end
            
            if ~isempty(algo_text)
                set(algo_text, 'Value', sprintf('Algorithm: %s\nMode: %s\nIterations: %d\nConvergence: %.6f', ...
                    'KFV & FGO', 'iEKF + Optimization', 100, 1e-6));
            end
            
            if ~isempty(perf_text) && ~isempty(kfv_results) && ~isempty(fgo_results)
                kfv_rmse = sqrt(mean(kfv_results.^2));
                fgo_rmse = sqrt(mean(fgo_results.^2));
                set(perf_text, 'Value', sprintf('RMSE KFV: %.3f m\nRMSE FGO: %.3f m\nCPU Usage: %.1f%%\nMemory: %.1f MB', ...
                    kfv_rmse, fgo_rmse, rand()*30+40, rand()*100+200));
            end
            
            if ~isempty(time_text)
                set(time_text, 'Value', sprintf('Start Time: %s\nElapsed: %.2f s\nEstimated: %.2f s', ...
                    datestr(now, 'HH:MM:SS'), rand()*10+2, rand()*5+8));
            end
        end

            function [est_positions, errors, processing_time] = runSelectedKFEstimator(app, data)
        tic;
        
        % 获取选择的滤波器类型
        selected_kf = app.data.selected_kf;
        
        % 准备数据
        true_positions = data.true_positions;
        position_measurements = data.position_measurements;
        pdr_positions = data.pdr_positions;
        num_steps = data.num_steps;
        
        try
            switch selected_kf
                case 'KF'
                    estimator = KF();
                case 'EKF'
                    estimator = EKF();
                case 'IEKF'
                    estimator = IEKF();
                case 'MIEKF'
                    estimator = MIEKF();
                otherwise
                    estimator = KF(); % 默认使用KF
            end
            
            % 调用estimator的run方法
            result = estimator.run(data);
            est_positions = result.positions;
            
        catch ME
            warning('Failed to use %s estimator: %s. Using fallback KF.', selected_kf, ME.message);
            % 如果调用失败，使用简化的KF实现
            [est_positions, ~] = app.runFallbackKF(data);
        end
        
        % 计算误差
        errors = sqrt(sum((est_positions - true_positions').^2, 2));
        processing_time = toc;
    end
    
    function [est_positions, prd_positions] = runFallbackKF(app, data)
        % 简化的KF实现作为后备方案
        true_positions = data.true_positions;
        position_measurements = data.position_measurements;
        pdr_positions = data.pdr_positions;
        num_steps = data.num_steps;
        
        pdr_noise_std = ones(2,1) * 0.5;
        position_noise_std = ones(2,1) * 0.5;
        
        X_est = position_measurements(:,1);
        P_est = diag(position_noise_std).^2;
        
        F = eye(2);
        Q = diag(pdr_noise_std).^2;
        H = eye(2);
        R = diag(position_noise_std).^2;
        B = eye(2);
        
        est_positions = X_est';
        prd_positions = X_est';
        
        for i = 1:num_steps
            U = pdr_positions(:,i);
            Z = position_measurements(:,i+1);
            
            [X_est, P_est, X_pred, ~] = app.kf(X_est, P_est, F, Q, B, U, Z, H, R);
            
            est_positions = [est_positions; X_est'];
            prd_positions = [prd_positions; X_pred'];
        end
    end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            addpath(fullfile(fileparts(mfilename('fullpath')), '..', 'config'));
            addpath(fullfile(fileparts(mfilename('fullpath')), '..', 'core'));
            addpath(fullfile(fileparts(mfilename('fullpath')), '..', 'core','estimator'));
            addpath(fullfile(fileparts(mfilename('fullpath')), '..', 'core','filter'));
            addpath(fullfile(fileparts(mfilename('fullpath')), '..', 'core','fgo'));

            % 初始化选择的KF
            app.data.selected_kf = app.SelectKFDropDown.Value;
            
            % 更新右侧图表
            app.updateRightPanels();
            
            % 更新Dashboard
            algo_text = app.algo_text;
            perf_text = app.perf_text;
            time_text = app.time_text;
            
%             if ~isempty(status_text)
%                 set(status_text, 'String', 'System Status: Running Analysis...');
%             end
            
            if ~isempty(algo_text)
                set(algo_text, 'Value', sprintf('Algorithm: %s\nMode: %s\nIterations: %d\nConvergence: %.6f', ...
            'KFV', 'iEKF', 0, 0));
            end


                set(perf_text, 'Value', sprintf('RMSE KFV: %.3f m\nRMSE FGO: %.3f m\nCPU Usage: %.1f%%\nMemory: %.1f MB', ...
            0, 0, 0, 0));
            
            
            if ~isempty(time_text)
                set(time_text, 'Value', sprintf('Start Time: %s\nElapsed: %.2f s\nEstimated: %.2f s', ...
            datestr(now, 'HH:MM:SS'), 0, 0));
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            % 打开文件选择对话框
            [filename, pathname] = uigetfile({'*.mat', 'MAT-files (*.mat)'}, 'Select a MAT file');
            
            if isequal(filename, 0) || isequal(pathname, 0)
                disp('User canceled file selection');
                return;
            end
            
            % 加载数据文件
            full_path = fullfile(pathname, filename);
            try
                loaded_data = load(full_path);
                
                % 检查必要的数据字段是否存在
                required_fields = {'true_positions', 'position_measurements', 'pdr_positions', 'num_steps'};
                missing_fields = setdiff(required_fields, fieldnames(loaded_data));
                
                if ~isempty(missing_fields)
                    msg = sprintf('The selected file is missing required data fields: %s', strjoin(missing_fields, ', '));
                    errordlg(msg, 'Invalid Data File');
                    return;
                end
                
                % 保存加载的数据到figure的UserData中
                
                app.data.loaded_data = loaded_data;
                
                % 更新Dashboard状态
                % status_text = findobj(fig, 'Tag', 'StatusText');
                % if ~isempty(status_text)
                %     set(status_text, 'String', sprintf('Data Loaded: %s', filename));
                % end
                
                disp(['Successfully loaded data from: ' full_path]);
            catch ME
                errordlg(['Error loading file: ' ME.message], 'File Load Error');
            end
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            disp('Simulation paused');
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            % 执行算法分析
            loaded_data = app.data.loaded_data;

            % 检查是否有数据加载
            if isempty(loaded_data)
                errordlg('Please load data file first!', 'No Data');
                return;
            end
            
            % 更新Dashboard状态
            app.algo_text.Value = 'Status: Running Analysis...';
            drawnow;
            
            % ========== 使用选择的KF算法 ==========
            fprintf('Running %s algorithm...\n', app.data.selected_kf);
            [kfv_positions, kfv_errors, kfv_time] = app.runSelectedKFEstimator(loaded_data);
            app.data.processing_time.kfv_time = kfv_time;
            
            % 存储KFV结果
            app.data.kfv_results = struct('positions', kfv_positions, 'errors', kfv_errors);


            % ========== 模拟FGO结果 ==========
            tic;
            % 获取真实位置数据
            true_positions = loaded_data.true_positions;
            position_measurements = loaded_data.position_measurements;
            
            % 模拟FGO算法
            fgo_positions = kfv_positions + randn(size(kfv_positions)) * 0.1; % 使用kfv_positions而不是est_positions
            fgo_time = toc;
            
            % 计算FGO误差
            fgo_errors = sqrt(sum((fgo_positions - true_positions').^2, 2));
            
            % 存储FGO结果
            app.data.fgo_results = struct('positions', fgo_positions, 'errors', fgo_errors);
            app.processing_time.fgo_time = fgo_time;
            
            % 模拟CPU使用率
            app.data.cpu_usage = [app.processing_time.kfv_time, fgo_time, 1, 5]; % 基于真实时间
            
            % 查找结果绘图区域
            plot_axes = app.UIAxes;
            
            % 清除先前的绘图
            cla(plot_axes);
            
            % 绘制结果
            hold(plot_axes, 'on');
            plot(plot_axes, true_positions(1,:), true_positions(2,:), 'b.-', 'LineWidth', 1.5, 'MarkerSize', 10);
            plot(plot_axes, position_measurements(1,:), position_measurements(2,:), 'r.--', 'LineWidth', 1.2);
            plot(plot_axes, kfv_positions(:, 1), kfv_positions(:, 2), 'g.-', 'LineWidth', 1.5, 'MarkerSize', 10); % 使用kfv_positions
            plot(plot_axes, fgo_positions(:, 1), fgo_positions(:, 2), 'm.-', 'LineWidth', 1.5, 'MarkerSize', 10);
            legend(plot_axes, 'True Trajectory', 'Noisy Measurements', sprintf('%s Result', app.data.selected_kf), 'FGO Result');
            xlabel(plot_axes, 'X Position (m)'); 
            ylabel(plot_axes, 'Y Position (m)');
            title(plot_axes, sprintf('2D Trajectory Comparison - %s vs FGO', app.data.selected_kf)); 
            axis(plot_axes, 'equal');
            hold(plot_axes, 'off');
            
            % 更新右侧图表
            app.updateRightPanels();
            
            % 更新Dashboard
            app.updateDashboard(kfv_errors, fgo_errors);
            
            % 显示完成状态
            app.algo_text.Value = sprintf('Analysis Complete\nAlgorithm: %s & FGO\nKFV RMSE: %.3f m\nFGO RMSE: %.3f m', ...
                app.data.selected_kf, sqrt(mean(kfv_errors.^2)), sqrt(mean(fgo_errors.^2)));
            
            drawnow;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            msgbox('Help information for the navigation simulator', 'Help');
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            choice = questdlg('Are you sure you want to delete current data?', ...
                'Delete Confirmation', ...
                'Yes', 'No', 'No');
            if strcmp(choice, 'Yes')
                disp('Data deleted');
            end
        end

        % Callback function: Button_6, KFCheckBox
        function Button_6Pushed(app, event)
            choice = questdlg('Setting', ...
                'Delete Confirmation', ...
                'Option 1', 'Option 2', 'Option 3');
            if strcmp(choice, 'Option 1')
                disp('Option 1!');
            end
        end

        % Button pushed function: SimulationModeButton
        function SimulationModeButtonPushed(app, event)
            app.SimulationModeLabel.Visible="on";
            app.Panel.Visible="on";
            app.FileModeLabel.Visible="off";
            app.Panel_2.Visible="off";
        end

        % Button pushed function: RealworldModeButton
        function RealworldModeButtonPushed(app, event)
            app.SimulationModeLabel.Visible="off";
            app.Panel.Visible="off";
            app.FileModeLabel.Visible="on";
            app.Panel_2.Visible="on";
        end

        % Callback function: not associated with a component
        function SelectKFDropDownOpening(app, event)
            selected_kf = app.SelectKFDropDown.Value;
    
        % 更新data结构中的选择
        app.data.selected_kf = selected_kf;
        
        % 更新Dashboard显示
        % app.algo_text.Value = sprintf('Selected KF: %s\nStatus: Ready\nData: %s', selected_kf, isempty(app.data.loaded_data) ? 'Not Loaded' : 'Loaded');
        
        fprintf('KF algorithm changed to: %s\n', selected_kf);
        end

        % Drop down opening function: SelectKFDropDown
        function SelectKFDropDownValueChanged(app, event)
            selected_kf = app.SelectKFDropDown.Value;
            
            % 更新data结构中的选择
            app.data.selected_kf = selected_kf;
            
            % 更新Dashboard显示
            if isempty(app.data.loaded_data)
                data_status = 'Not Loaded';
            else
                data_status = 'Loaded';
            end
            app.algo_text.Value = sprintf('Selected KF: %s\nStatus: Ready\nData: %s', ...
                selected_kf, data_status);
            
            fprintf('KF algorithm changed to: %s\n', selected_kf);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FactorGraphOptimizationSimulatorUIFigure = uifigure('Visible', 'off');
            app.FactorGraphOptimizationSimulatorUIFigure.Name = 'Factor Graph Optimization Simulator';
            app.Panel_2 = uipanel(app.FactorGraphOptimizationSimulatorUIFigure);
            app.Panel = uipanel(app.FactorGraphOptimizationSimulatorUIFigure);
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, '2D PDR Trajectory and Anchors')
            xlabel(app.UIAxes, 'X Position (m)')
            ylabel(app.UIAxes, 'Y Position (m)')
            zlabel(app.UIAxes, 'Z')
            app.ErrorAxes = uiaxes(app.Panel);
            title(app.ErrorAxes, 'Title')
            app.CPUAxes = uiaxes(app.Panel);
            title(app.CPUAxes, 'Title')
            app.KFVAxes = uiaxes(app.Panel);
            title(app.KFVAxes, 'Title')
            app.FGOAxes = uiaxes(app.Panel);
            title(app.FGOAxes, 'Title')
            app.Button = uibutton(app.Panel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '?';
            app.Button_2 = uibutton(app.Panel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '📁';
            app.Button_3 = uibutton(app.Panel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '⏸';
            app.Button_4 = uibutton(app.Panel, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '▶';
            app.Button_5 = uibutton(app.Panel, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '🗑';
            app.Button_6 = uibutton(app.Panel, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '⚙';
            app.NoiseLabel = uilabel(app.Panel);
            app.NoiseLabel.Text = 'Noise';
            app.Panel_4 = uipanel(app.Panel);
            app.GaussianmodelDropDownLabel = uilabel(app.Panel_4);
            app.GaussianmodelDropDownLabel.Text = 'Gaussian model';
            app.GaussianmodelDropDown = uidropdown(app.Panel_4);
            app.GaussianmodelDropDown.Items = {'Unimodal', 'Heavy tail', 'GMM'};
            app.GaussianmodelDropDown.Value = 'Unimodal';
            app.KernelDropDownLabel = uilabel(app.Panel_4);
            app.KernelDropDownLabel.Text = 'Kernel';
            app.KernelDropDown = uidropdown(app.Panel_4);
            app.KernelDropDown.Items = {'None', 'Huber', 'Cauchy'};
            app.KernelDropDown.Value = 'None';
            app.KFVariantsLabel = uilabel(app.Panel);
            app.KFVariantsLabel.Text = 'KF Variants';
            app.Panel_5 = uipanel(app.Panel);
            app.KFCheckBox = uicheckbox(app.Panel_5);
            app.KFCheckBox.ValueChangedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.KFCheckBox.Text = 'KF';
            app.EKFCheckBox = uicheckbox(app.Panel_5);
            app.EKFCheckBox.Text = 'EKF';
            app.IEKFCheckBox = uicheckbox(app.Panel_5);
            app.IEKFCheckBox.Text = 'IEKF';
            app.MIEKFCheckBox = uicheckbox(app.Panel_5);
            app.MIEKFCheckBox.Text = 'MIEKF';
            app.REKFCheckBox = uicheckbox(app.Panel_5);
            app.REKFCheckBox.Text = 'REKF';
            app.RTSSCheckBox = uicheckbox(app.Panel_5);
            app.RTSSCheckBox.Text = 'RTSS';
            app.SWEKFCheckBox = uicheckbox(app.Panel_5);
            app.SWEKFCheckBox.Text = 'SWEKF';
            app.SelectKFDropDownLabel = uilabel(app.Panel_5);
            app.SelectKFDropDownLabel.Text = 'Select KF:';
            app.SelectKFDropDown = uidropdown(app.Panel_5);
            app.SelectKFDropDown.Items = {'KF', 'EKF', 'IEKF', 'MIEKF', 'REKF', 'RTSS', 'SWEKF'};
            app.SelectKFDropDown.DropDownOpeningFcn = createCallbackFcn(app, @SelectKFDropDownValueChanged, true);
            app.SelectKFDropDown.Value = 'KF';
            app.DashboardPanel = uipanel(app.Panel);
            app.DashboardPanel.Title = 'Dashboard';
            app.algo_text = uitextarea(app.DashboardPanel);
            app.perf_text = uitextarea(app.DashboardPanel);
            app.time_text = uitextarea(app.DashboardPanel);
            app.FileModeLabel = uilabel(app.FactorGraphOptimizationSimulatorUIFigure);
            app.FileModeLabel.Text = 'File Mode';
            app.SimulationModeButton = uibutton(app.FactorGraphOptimizationSimulatorUIFigure, 'push');
            app.SimulationModeButton.ButtonPushedFcn = createCallbackFcn(app, @SimulationModeButtonPushed, true);
            app.SimulationModeButton.Text = 'Simulation Mode';
            app.RealworldModeButton = uibutton(app.FactorGraphOptimizationSimulatorUIFigure, 'push');
            app.RealworldModeButton.ButtonPushedFcn = createCallbackFcn(app, @RealworldModeButtonPushed, true);
            app.RealworldModeButton.Text = 'Real-world Mode';
            app.SimulationModeLabel = uilabel(app.FactorGraphOptimizationSimulatorUIFigure);
            app.SimulationModeLabel.Text = 'Simulation Mode';
            % Show the figure after all components are created
            app.FactorGraphOptimizationSimulatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FGO_KF_Simulator0617

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FactorGraphOptimizationSimulatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FactorGraphOptimizationSimulatorUIFigure)
        end
    end
end